classdef TxRx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GLTEPhysicalLayerUIFigure       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InitializationTab               matlab.ui.container.Tab
        FFTSizeEditFieldLabel           matlab.ui.control.Label
        FFTSizeEditField                matlab.ui.control.NumericEditField
        ModulationMethodButtonGroup     matlab.ui.container.ButtonGroup
        BPSKButton                      matlab.ui.control.RadioButton
        QPSKButton                      matlab.ui.control.RadioButton
        PSKButton                       matlab.ui.control.RadioButton
        QAMButton                       matlab.ui.control.RadioButton
        QAMButton_2                     matlab.ui.control.RadioButton
        QAMButton_3                     matlab.ui.control.RadioButton
        SNREditFieldLabel               matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        ChannelTapsEditFieldLabel       matlab.ui.control.Label
        ChannelTapsEditField            matlab.ui.control.NumericEditField
        ChannelEstimationCheckBox       matlab.ui.control.CheckBox
        StateLampLabel                  matlab.ui.control.Label
        StateLamp                       matlab.ui.control.Lamp
        LoadImageButton                 matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        InputOutputTab                  matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        SNREditField_2Label             matlab.ui.control.Label
        SNREditField_2                  matlab.ui.control.NumericEditField
        BEREditFieldLabel               matlab.ui.control.Label
        BEREditField                    matlab.ui.control.NumericEditField
        ModulationTypeEditFieldLabel    matlab.ui.control.Label
        ModulationTypeEditField         matlab.ui.control.EditField
        ConstellationTab                matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        SNREditField_3Label             matlab.ui.control.Label
        SNREditField_3                  matlab.ui.control.NumericEditField
        BEREditField_2Label             matlab.ui.control.Label
        BEREditField_2                  matlab.ui.control.NumericEditField
        ModulationTypeEditField_2Label  matlab.ui.control.Label
        ModulationTypeEditField_2       matlab.ui.control.EditField
        BERvsSNRTab                     matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        ChannelImpulseResponseTab       matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        IFFTTimeDomainTab               matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        CarrierButton                   matlab.ui.control.Button
    end

    methods (Access = private)
        
        function [mod_order,n_fft,nfft,n_taps,snr,ch_est_method,mod_method]=readInput(app,im)
            n_fft=app.FFTSizeEditField.Value;
            nfft=app.FFTSizeEditField.Value;
            snr=app.SNREditField.Value;
            n_taps=app.ChannelTapsEditField.Value;
            
            if app.ChannelEstimationCheckBox.Value
                ch_est_method='LS';
            else
                ch_est_method='';
            end
            
            if app.BPSKButton.Value
                mod_method = 'BPSK';
            elseif app.QPSKButton.Value
                mod_method = 'QPSK';
            elseif app.PSKButton.Value
                mod_method = '8PSK';
            elseif app.QAMButton.Value
                mod_method = '16QAM';
            elseif app.QAMButton_2.Value
                mod_method = '32QAM';
            else
                mod_method = '64QAM';
            end
            
            mod_methods = {'BPSK', 'QPSK', '8PSK', '16QAM', '32QAM', '64QAM'};
            
            mod_order = find(ismember(mod_methods,mod_method));
            
            imshow(im,'Parent',app.UIAxes);
        end
        
        function [cons_sym_id,sym_rem ] = stream2Symbol(app,im_bin,mod_order)
            %% Binary stream to symbols
            % 1. parse binary stream into mod_order bit symbols
            % 2. pads input signal to appropriate length
            
            sym_rem = mod(mod_order-mod(length(im_bin),mod_order),mod_order);
            padding = repmat('0',sym_rem,1);
            im_bin_padded = [im_bin;padding];
            cons_data = reshape(im_bin_padded,mod_order,length(im_bin_padded)/mod_order)';
            cons_sym_id = bin2dec(cons_data);
        end
        
        function symbol_book = symbolModulation(app,mod_order)
            %% symbol modulation
            % BPSK
            if mod_order == 1
                mod_ind = 2^(mod_order-1);
                n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                in_phase = cos(n);
                quadrature = sin(n);
                symbol_book = (in_phase + quadrature*1i);
            end
            
            % Phase shift keying about unit circle
            if mod_order == 2 || mod_order == 3
                mod_ind = 2^(mod_order-1);
                n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                in_phase = cos(n+pi/4);
                quadrature = sin(n+pi/4);
                symbol_book = (in_phase + quadrature*1i);
            end
            
            %16QAM, 64QAM
            if mod_order == 4 || mod_order == 6
                mod_ind = sqrt(2^mod_order);
                %n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                in_phase = repmat(linspace(-1,1,mod_ind),mod_ind,1);
                quadrature = repmat(linspace(-1,1,mod_ind)',1,mod_ind);
                symbol_book = (in_phase(:) + quadrature(:)*1i);
            end
            
            %32QAM - Generates 6x6 constellation and removes corners
            if mod_order == 5
                mod_ind = 6;
                %n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                in_phase = repmat(linspace(-1,1,mod_ind),mod_ind,1);
                quadrature = repmat(linspace(-1,1,mod_ind)',1,mod_ind);
                symbol_book = (in_phase(:) + quadrature(:)*1i);
                symbol_book = symbol_book([2:5 7:30 32:35]); %corners are removed
            end
            
        end
        
        function [x_s,X_blocks,fft_rem] = freq2Time(app,X,n_fft,nfft,n_cpe)
            %% Use IFFT to move to time domain
            % pad input signal to appropriate length
            fft_rem = mod(n_fft-mod(length(X),n_fft),n_fft);
            X_padded = [X;zeros(fft_rem,1)];
            X_blocks = reshape(X_padded,nfft,length(X_padded)/nfft);
            x = ifft(X_blocks);
            
            %Add cyclic prefix entension and shift from parallel to serial
            x_cpe = [x(end-n_cpe+1:end,:);x];
            x_s   = x_cpe(:);
        end
        
        function [snr_meas,x_s_noise_fading,g] = Noise(app,x_s,snr,n_taps)
            %% Add AWGN
            % Calculate data power
            data_pwr = mean(abs(x_s.^2));
            
            % Add noise to the channel
            noise_pwr = data_pwr/10^(snr/10);
            noise = normrnd(0,sqrt(noise_pwr/2),size(x_s))+normrnd(0,sqrt(noise_pwr/2),size(x_s))*1i;
            x_s_noise = x_s + noise;
            
            % Measure SNR
            snr_meas = 10*log10(mean(abs(x_s.^2))/mean(abs(noise.^2)));
            
            %% Apply fading channel
            g = exp(-(0:n_taps-1));
            g = g/norm(g);
            x_s_noise_fading = conv(x_s_noise,g,'same');
            
        end
        
        function X_hat_blocks = cpRemoval(app,x_s_noise_fading,n_cpe,nfft)
            %% Use FFT to move to frequency domain
            % Remove cyclic prefix extension and shift from serial to parallel
            x_p = reshape(x_s_noise_fading,nfft+n_cpe,length(x_s_noise_fading)/(nfft+n_cpe));
            x_p_cpr = x_p(n_cpe+1:end,:);
            
            % Move to frequency domain
            X_hat_blocks = fft(x_p_cpr);
        end
        
        function X_hat_blocks = channelEst(app,X_hat_blocks,X_blocks,n_taps,ch_est_method)
            %% Estimate channels
            if n_taps > 1
                switch(ch_est_method)
                    case 'none'
                    case 'LS'
                        G = X_hat_blocks(:,1)./X_blocks(:,1);
                        X_hat_blocks = X_hat_blocks./repmat(G,1,size(X_hat_blocks,2));
                end
            end
        end
        
        function [X_hat,rec_syms]= Demodulation(app,X_hat_blocks,fft_rem,symbol_book)
            %% Symbol demodulation
            % remove fft padding
            X_hat = X_hat_blocks(:);
            X_hat = X_hat(1:end-fft_rem);
            
            %Recover data from modulated symbols
            A=[real(symbol_book) imag(symbol_book)];
            if (size(A,2)>2)
                A=[real(symbol_book)' imag(symbol_book)'];
            end
            rec_syms = knnsearch(A,[real(X_hat) imag(X_hat)])-1;
        end
        
        function [ber,rec_im] = Recovery(app,rec_syms,sym_rem,im_bin,im)
            %Parse to binary stream to remove symbol padding
            rec_syms_cons = dec2bin(rec_syms);
            rec_im_bin = reshape(rec_syms_cons',numel(rec_syms_cons),1);
            rec_im_bin = rec_im_bin(1:end-sym_rem);
            ber = sum(abs(rec_im_bin-im_bin))/length(im_bin);
            
            %% recover image
            % rec_im = reshape(rec_im_bin,9,numel(rec_im_bin)/8);
            rec_im = reshape(rec_im_bin,8,numel(rec_im_bin)/8);
            rec_im = uint8(bin2dec(rec_im'));
            rec_im = reshape(rec_im,size(im));
        end
        
        function plotConstellation(app,X,X_hat,mod_method,n_taps,snr_meas,ch_est_method)
            %% generate plots
            % transmit constellation
            plot(app.UIAxes2,X,'x','linewidth',2,'markersize',10);
            app.UIAxes2.XLim=[-2,2];
            app.UIAxes2.YLim=[-2 2];
            app.UIAxes2.XLabel.String='In phase';
            app.UIAxes2.YLabel.String='Qudrature';
            
         
            app.UIAxes2.Title.String=(sprintf('\\bfTransmit Constellation'));
            app.SNREditField.Value=snr_meas;
            app.SNREditField_2.Value=snr_meas;
            app.ModulationTypeEditField.Value=mod_method;
            app.ModulationTypeEditField_2.Value=mod_method;
            
            app.UIAxes2.XGrid = 'on';
            app.UIAxes2.YGrid = 'on';
            %             grid on
            
            % Recovered constellation
            plot(app.UIAxes3,X_hat(1:500:end),'x','markersize',3);
            app.UIAxes3.XLim=[-2 2];
            app.UIAxes3.YLim=[-2 2];
            app.UIAxes3.XLabel.String='In phase';
            app.UIAxes3.YLabel.String='Qudrature';
            %
            
            app.UIAxes3.Title.String=(sprintf('\\bfReceived Constellation'));
     
            app.UIAxes3.XGrid = 'on';
            app.UIAxes3.YGrid = 'on';
            %             grid on
        end
        
        function showIO(app,ber,im,rec_im)
            % Original image
            imshow(im,'Parent',app.UIAxes);
            app.UIAxes.Title.String='\bfTransmit Image';
            
            app.BEREditField.Value=ber;
            app.BEREditField_2.Value=ber;
            
            % Recovered image
            imshow(rec_im,'Parent',app.UIAxes4);
%             app.UIAxes4.Title.String=sprintf('\bf Recovered Image');
        end
        function plotBer(app,im)
            % Original image
            imshow(im,'Parent',app.UIAxes5);
%             app.UIAxes5.Title.String='\bfTransmit Image';
            
        end
        
        function berPlot(app,data_pwr,x_s,g,A,X_blocks,fft_rem,sym_rem,im_bin)
            for snr1=1:25
                % Add noise to the channel
                noise_pwr1 = data_pwr/10^(snr1/10);
                noise1 = normrnd(0,sqrt(noise_pwr1/2),size(x_s))+normrnd(0,sqrt(noise_pwr1/2),size(x_s))*1i;
                x_s_noise1 = x_s(1:5120) + noise1(1:5120);
                
                % Measure SNR
%                 snr_meas1 = 10*log10(mean(abs(x_s.^2))/mean(abs(noise1.^2)));
                
                x_s_noise_fading1 = conv(x_s_noise1,g,'same');
                % Remove cyclic prefix extension and shift from serial to parallel
                x_p1 = reshape(x_s_noise_fading1,80,length(x_s_noise_fading1)/(80));
                x_p_cpr1 = x_p1(16+1:end,:);
                
                % Move to frequency domain
                X_hat_blocks1 = fft(x_p_cpr1);
                n_taps=3;
                ch_est_method='LS';
                if n_taps > 1
                    switch(ch_est_method)
                        case 'none'
                        case 'LS'
                            G1 = X_hat_blocks1(:,1)./X_blocks(:,1);
                            X_hat_blocks1 = X_hat_blocks1./repmat(G1,1,size(X_hat_blocks1,2));
                    end
                end
                % remove fft padding
                X_hat1 = X_hat_blocks1(:);
                X_hat1 = X_hat1(1:end-fft_rem);
                
                
                rec_syms1 = knnsearch(A,[real(X_hat1) imag(X_hat1)])-1;
                
                %Parse to binary stream to remove symbol padding
                rec_syms_cons1 = dec2bin(rec_syms1);
                rec_im_bin1 = reshape(rec_syms_cons1',numel(rec_syms_cons1),1);
                rec_im_bin1 = rec_im_bin1(1:end-sym_rem);
                ber = sum(abs(rec_im_bin1-im_bin(1:4096)))/4096;
                B(snr1)=ber;
            end
            plot(app.UIAxes5,1:25,B,'linewidth',2);
            app.UIAxes5.Title.String='\bfBer vs SNR graph';
            app.UIAxes5.XLabel.String='SNR';
            app.UIAxes5.YLabel.String='BER';
        end
        
        function chnmIpulse(app,g)
            channelResponse = g;  % impulse response of the wireless channel
            H_exact = fft(channelResponse, 64);
            stem(app.UIAxes8,0:63,abs(H_exact));
            app.UIAxes8.Title.String='\bfChannel Impulse Response';
            app.UIAxes8.XLabel.String='Frequency';
            app.UIAxes8.YLabel.String='Gain';
        end
        
        function OFDM(app,F)
            OS=10;
            N=64;
            a=rand(1,4);
            hold(app.UIAxes6,'on')
            plot(app.UIAxes6,real(a(1)*F(:,2)));
            plot(app.UIAxes6,real(a(2)*F(:,4)));
            plot(app.UIAxes6,real(a(3)*F(:,6)));
            plot(app.UIAxes6,real(a(4)*F(:,8)));
            hold(app.UIAxes6,'off')
            sig_amp=abs(fftshift((fft(F(:,20),OS*64))));
            hold(app.UIAxes7,'on')
            plot(app.UIAxes7,-OS*N/2:1:OS*N/2-1,a(1)*sig_amp);
            plot(app.UIAxes7,-OS*N/2:1:OS*N/2-1,fftshift(a(2)*abs(fft(F(:,5),OS*N))));
            plot(app.UIAxes7,-OS*N/2:1:OS*N/2-1,fftshift(a(3)*abs(fft(F(:,10),OS*N))));
            plot(app.UIAxes7,-OS*N/2:1:OS*N/2-1,fftshift(a(4)*abs(fft(F(:,15),OS*N))));
            hold(app.UIAxes6,'off')

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global filename
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            app.EditField.Value=filename;
            app.StateLamp.Color=[0 0.4470 0.7410];

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            global filename x_s symbol_book g X_blocks fft_rem sym_rem im_bin;
            im=imread(filename);
            imshow(im,'Parent',app.UIAxes);
            im_bin = dec2bin(im(:))';
            im_bin = im_bin(:);
            
            [mod_order,n_fft,nfft,n_taps,snr,ch_est_method,mod_method]=readInput(app,im);
            n_cpe=nfft/4;
            [cons_sym_id,sym_rem ] = stream2Symbol(app,im_bin,mod_order);
            
            symbol_book=symbolModulation(app,mod_order);
            
            %modulate data according to the symbol_book
            X = symbol_book(cons_sym_id+1);
            
            [x_s,X_blocks,fft_rem] = freq2Time(app,X,n_fft,nfft,n_cpe);
            
            [snr_meas,x_s_noise_fading,g] = Noise(app,x_s,snr,n_taps);
            
            X_hat_blocks = cpRemoval(app,x_s_noise_fading,n_cpe,nfft);
            
            X_hat_blocks = channelEst(app,X_hat_blocks,X_blocks,n_taps,ch_est_method);
            
            [X_hat,rec_syms]= Demodulation(app,X_hat_blocks,fft_rem,symbol_book);
            
            [ber,rec_im] = Recovery(app,rec_syms,sym_rem,im_bin,im);
            
            showIO(app,ber,im,rec_im)
            
            plotConstellation(app,X,X_hat,mod_method,n_taps,snr_meas,ch_est_method)
            
            app.StateLamp.Color=[0.4660 0.6740 0.1880];
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global x_s symbol_book g X_blocks fft_rem sym_rem im_bin;
            data_pwr = mean(abs(x_s.^2));
            %Recover data from modulated symbols
            A=[real(symbol_book) imag(symbol_book)];
            if (size(A,2)>2)
                A=[real(symbol_book)' imag(symbol_book)'];
            end
            berPlot(app,data_pwr,x_s,g,A,X_blocks,fft_rem,sym_rem,im_bin);
            chnmIpulse(app,g);
        end

        % Button pushed function: CarrierButton
        function CarrierButtonPushed(app, event)
            N=64;
            K=64;
            M1=(0:N-1).'*(0:K-1);
            
            omega=exp(-1i*2*pi/N);
            omega_in=exp(1i*2*pi/N);
            F=(omega.^M1)/sqrt(N);
            
            OFDM(app,F)
            
%             OS=10;
%             i=5;
%             
%             figure(1)
%             hold on;
%             plot(real(F(:,5)));
%             plot(real(F(:,10)));
%             plot(real(F(:,15)));
%             plot(real(F(:,2)));
%             hold off;
%             sig_amp=fftshift((fft(F(:,2),OS*N)));
%             sig_mag=fftshift(abs(fft(F_in(:,2),OS*N)));
%             figure(4)
%             hold on;
%             plot(-OS*N/2:1:OS*N/2-1,sig_amp);
%             plot(-OS*N/2:1:OS*N/2-1,fftshift((fft(F(:,5),OS*N))));
%             plot(-OS*N/2:1:OS*N/2-1,fftshift((fft(F(:,10),OS*N))));
%             plot(-OS*N/2:1:OS*N/2-1,fftshift((fft(F(:,15),OS*N))));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GLTEPhysicalLayerUIFigure = uifigure('Visible', 'off');
            app.GLTEPhysicalLayerUIFigure.Name = '4G LTE Physical Layer';
            app.TabGroup = uitabgroup(app.GLTEPhysicalLayerUIFigure);
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.FFTSizeEditFieldLabel = uilabel(app.InitializationTab);
            app.FFTSizeEditFieldLabel.Text = 'FFT Size';
            app.FFTSizeEditField = uieditfield(app.InitializationTab, 'numeric');
            app.FFTSizeEditField.Value = 64;
            app.ModulationMethodButtonGroup = uibuttongroup(app.InitializationTab);
            app.ModulationMethodButtonGroup.Title = 'Modulation Method';
            app.BPSKButton = uiradiobutton(app.ModulationMethodButtonGroup);
            app.BPSKButton.Text = 'BPSK';
            app.BPSKButton.Value = true;
            app.QPSKButton = uiradiobutton(app.ModulationMethodButtonGroup);
            app.QPSKButton.Text = 'QPSK';
            app.PSKButton = uiradiobutton(app.ModulationMethodButtonGroup);
            app.PSKButton.Text = '8PSK';
            app.QAMButton = uiradiobutton(app.ModulationMethodButtonGroup);
            app.QAMButton.Text = '16QAM';
            app.QAMButton_2 = uiradiobutton(app.ModulationMethodButtonGroup);
            app.QAMButton_2.Text = '32QAM';
            app.QAMButton_3 = uiradiobutton(app.ModulationMethodButtonGroup);
            app.QAMButton_3.Text = '64QAM';
            app.SNREditFieldLabel = uilabel(app.InitializationTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.InitializationTab, 'numeric');
            app.SNREditField.Value = 20;
            app.ChannelTapsEditFieldLabel = uilabel(app.InitializationTab);
            app.ChannelTapsEditFieldLabel.Text = 'Channel Taps';
            app.ChannelTapsEditField = uieditfield(app.InitializationTab, 'numeric');
            app.ChannelTapsEditField.Value = 20;
            app.ChannelEstimationCheckBox = uicheckbox(app.InitializationTab);
            app.ChannelEstimationCheckBox.Text = 'Channel Estimation';
            app.ChannelEstimationCheckBox.Value = true;
            app.StateLampLabel = uilabel(app.InitializationTab);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.InitializationTab);
            app.LoadImageButton = uibutton(app.InitializationTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SimulateButton = uibutton(app.InitializationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.EditField = uieditfield(app.InitializationTab, 'text');
            app.InputOutputTab = uitab(app.TabGroup);
            app.InputOutputTab.Title = 'Input & Output';
            app.UIAxes = uiaxes(app.InputOutputTab);
            title(app.UIAxes, 'Transmitted Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes4 = uiaxes(app.InputOutputTab);
            title(app.UIAxes4, 'Recovered Image')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.UIAxes4.YTickLabel = '';
            app.SNREditField_2Label = uilabel(app.InputOutputTab);
            app.SNREditField_2Label.Text = 'SNR';
            app.SNREditField_2 = uieditfield(app.InputOutputTab, 'numeric');
            app.BEREditFieldLabel = uilabel(app.InputOutputTab);
            app.BEREditFieldLabel.Text = 'BER';
            app.BEREditField = uieditfield(app.InputOutputTab, 'numeric');
            app.ModulationTypeEditFieldLabel = uilabel(app.InputOutputTab);
            app.ModulationTypeEditFieldLabel.Text = 'Modulation Type';
            app.ModulationTypeEditField = uieditfield(app.InputOutputTab, 'text');
            app.ConstellationTab = uitab(app.TabGroup);
            app.ConstellationTab.Title = 'Constellation';
            app.UIAxes2 = uiaxes(app.ConstellationTab);
            title(app.UIAxes2, 'Transmitted Constellation')
            xlabel(app.UIAxes2, 'In Phase')
            ylabel(app.UIAxes2, 'Quadraure')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.ConstellationTab);
            title(app.UIAxes3, 'Received Constellation')
            xlabel(app.UIAxes3, 'In Phase')
            ylabel(app.UIAxes3, 'Quadrature')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.SNREditField_3Label = uilabel(app.ConstellationTab);
            app.SNREditField_3Label.Text = 'SNR';
            app.SNREditField_3 = uieditfield(app.ConstellationTab, 'numeric');
            app.BEREditField_2Label = uilabel(app.ConstellationTab);
            app.BEREditField_2Label.Text = 'BER';
            app.BEREditField_2 = uieditfield(app.ConstellationTab, 'numeric');
            app.ModulationTypeEditField_2Label = uilabel(app.ConstellationTab);
            app.ModulationTypeEditField_2Label.Text = 'Modulation Type';
            app.ModulationTypeEditField_2 = uieditfield(app.ConstellationTab, 'text');
            app.BERvsSNRTab = uitab(app.TabGroup);
            app.BERvsSNRTab.Title = 'BER vs SNR';
            app.UIAxes5 = uiaxes(app.BERvsSNRTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.Button = uibutton(app.BERvsSNRTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ChannelImpulseResponseTab = uitab(app.TabGroup);
            app.ChannelImpulseResponseTab.Title = 'Channel Impulse Response';
            app.UIAxes8 = uiaxes(app.ChannelImpulseResponseTab);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.IFFTTimeDomainTab = uitab(app.TabGroup);
            app.IFFTTimeDomainTab.Title = 'IFFT Time Domain';
            app.UIAxes6 = uiaxes(app.IFFTTimeDomainTab);
            title(app.UIAxes6, 'IFFT')
            xlabel(app.UIAxes6, 'Time Domain')
            ylabel(app.UIAxes6, 'Amplitude')
            app.UIAxes6.XTickLabel = '';
            app.UIAxes6.YTickLabel = '';
            app.UIAxes7 = uiaxes(app.IFFTTimeDomainTab);
            title(app.UIAxes7, 'FFT')
            xlabel(app.UIAxes7, 'Frequency Domain')
            ylabel(app.UIAxes7, 'Mag')
            app.UIAxes7.XTickLabel = '';
            app.UIAxes7.YTickLabel = '';
            app.CarrierButton = uibutton(app.IFFTTimeDomainTab, 'push');
            app.CarrierButton.ButtonPushedFcn = createCallbackFcn(app, @CarrierButtonPushed, true);
            app.CarrierButton.Text = 'Carrier';
            % Show the figure after all components are created
            app.GLTEPhysicalLayerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TxRx

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GLTEPhysicalLayerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GLTEPhysicalLayerUIFigure)
        end
    end
end